classdef AiCam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AiCamUIFigure             matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        DevicesMenu               matlab.ui.container.Menu
        OptionsMenu               matlab.ui.container.Menu
        CaptureMenu               matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        PreviewTab                matlab.ui.container.Tab
        CameraListListBoxLabel    matlab.ui.control.Label
        CameraListListBox         matlab.ui.control.ListBox
        ResolutionsButtonGroup    matlab.ui.container.ButtonGroup
        PreviewButton             matlab.ui.control.Button
        CheckBox_slash_LT_RD      matlab.ui.control.CheckBox
        CaptureRecordTab          matlab.ui.container.Tab
        SavePathEditFieldLabel    matlab.ui.control.Label
        SavePathEditField         matlab.ui.control.EditField
        Button_Select_Path        matlab.ui.control.Button
        CapturePanel              matlab.ui.container.Panel
        CaptureFramesLabel        matlab.ui.control.Label
        CaptureFramesEditField    matlab.ui.control.NumericEditField
        CaptureButton             matlab.ui.control.Button
        RecordPanel               matlab.ui.container.Panel
        ButtonStartRecord         matlab.ui.control.Button
        ButtonStopRecord          matlab.ui.control.Button
        EditFieldRecordTime       matlab.ui.control.EditField
        RecordStateLamp           matlab.ui.control.Lamp
        UIAxes                    matlab.ui.control.UIAxes
        ImageProcessTab           matlab.ui.container.Tab
        Panel_Still_Image         matlab.ui.container.Panel
        ImageEditFieldLabel       matlab.ui.control.Label
        ImageEditField            matlab.ui.control.EditField
        Button_Select_image       matlab.ui.control.Button
        Button_RGB_Histogram      matlab.ui.control.Button
        GrayscaleHistogramButton  matlab.ui.control.Button
        mergeCheckBox             matlab.ui.control.CheckBox
        RGBMeanButton             matlab.ui.control.Button
        Panel_Real_Image          matlab.ui.container.Panel
    end

    
    properties (Access = public)
        glCamObj; % Camrea device obj
        glCamName % Camrea name
        glCamIndex % Camera index in camera list
        glSavePath % save path
        glResolution % resolution for preview
        glCaptureFrames % frames will be capture
        glSaveFormat % save capture image formating
        glResAvalible % current camera avalibale resolutions
        glRsListTotal % total number of previous camera
        glStopRecord  % Global flag that stop record
        glTimer
        glSelectImage
    end
    
    methods (Access = public)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.glCaptureFrames = 1;
            app.glRsListTotal = 0;
            app.glSavePath = '';
            app.glSaveFormat = '';
            app.glStopRecord = true;
            
            % Create a timer to on/off signal lamp
            app.glTimer = timer;
            app.glTimer.TimerFcn = @switch_lamp;
            app.glTimer.Period = 0.5; %  second
            app.glTimer.ExecutionMode = 'fixedSpacing';
            % Local functions
            i = 0;
            function switch_lamp(mTimer,~)
                i = i + 1;
                if (rem(i,2))
                    app.RecordStateLamp.Color = 'green';
                else
                    app.RecordStateLamp.Color = 'red';
                end
            end
            
            
            % List the camera device
            cl = webcamlist;
            n = size(cl);
            items = [];
            ItemsData = [];
            for i=1:n
                items = [items cl(i)];
                ItemsData = [ItemsData i];
            end
            if isempty(items)
                %警告对话框
                warndlg('没有可用摄像头！','警告','modal');
            else
                app.CameraListListBox.Items = items;
                app.CameraListListBox.ItemsData = ItemsData;% listbox.value
            end
            
        end

        % Value changed function: CameraListListBox
        function CameraListListBoxValueChanged(app, event)
            app.glCamIndex = app.CameraListListBox.Value;
            %             disp(app.glCamIndex)
            app.glCamName = app.CameraListListBox.Items(app.CameraListListBox.Value);
            %             disp(app.glCamName)
            app.glCamObj = webcam(app.glCamIndex);
            rs = app.glCamObj.AvailableResolutions;
            [~, app.glResAvalible] = size(rs);
            
            left = 11;
            bottom = app.ResolutionsButtonGroup.InnerPosition(4);
            width = app.ResolutionsButtonGroup.InnerPosition(3);%ButtonGroup width
            height = 20;
            
            % hide the old list
            if (app.glRsListTotal ~= 0)
                for i=1:app.glRsListTotal
                    app.ResolutionsButtonGroup.Children(i).Visible =  'off';
                end
            end
            
            % cover the old list by new resolutions list
            for i=1:app.glResAvalible
                bt = uiradiobutton(app.ResolutionsButtonGroup);
                bt.Text = rs(i);
                bt.Position = [left bottom-25*i width height];
                bt.Value = 1;
            end
            app.glRsListTotal = app.glResAvalible;
            str = sprintf('Resolutions(%d)', app.glResAvalible);
            app.ResolutionsButtonGroup.Title = str;
        end

        % Selection changed function: ResolutionsButtonGroup
        function ResolutionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ResolutionsButtonGroup.SelectedObject;
            app.glResolution = selectedButton.Text;
            app.glCamObj.Resolution = char(app.glResolution);
            %             disp(class(app.glResolution));
            %             disp(app.glResolution);
            
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if isempty(app.glCamIndex)
                warndlg('Please select a camera!','Warning','modal');
                return
            end
            if isempty(app.glResolution)
                sl=app.ResolutionsButtonGroup.SelectedObject;
                app.glResolution = sl.Text;
            end
            
            %% Setup Resolution
            app.glCamObj.Resolution = char(app.glResolution);
            
            % Start preview
            preview(app.glCamObj);
            
            % Setup preview window
            %             fig = figure('NumberTitle', 'off', 'MenuBar', 'none');
            %             fig.Name = char(app.glCamName);
            %             ax = axes(fig);
            %             disp(app.glResolution);
            %             frame = snapshot(app.glCamObj);
            %             hImage = image(ax, zeros(size(frame), 'uint8'));
            %             axis(ax, 'image');
            %             %Start preview
            %             preview(app.glCamObj, hImage);
            
            %             % Set the axis of the displayed image to maintain the aspect ratio of the
            %             % incoming frame.
            %             axis image;
            % %             axis(ax, 'image');
            
            %            setappdata(hImage,'UpdatePreviewWindowFcn',@update_preview_display);
            
            function update_preview_display(obj,event,hImage)
                % This callback function updates the displayed frame and the histogram.
                % Display the current image frame.
                %             set(hImage, 'CData', event.Data);
                disp('AAAAAAAAAAAAAAAAAAAaaaa')
                % Select the second subplot on the figure for the histogram.
                %             subplot(1,2,2);
                
                % Plot the histogram. Choose 128 bins for faster update of the display.
                %             imhist(event.Data, 128);
                
                % Refresh the display.
                %             drawnow
            end
            
            %            truesize(fig,[480 540])
            
            
            %             setappdata(fig, 'CamObj', app.glCamObj);
            %             fig.CloseRequestFcn = @closePreviewWindow_Callback;
            % Local functions
            function closePreviewWindow_Callback(obj, ~)
                CamObj = getappdata(obj, 'CamObj');
                closePreview(CamObj)
                delete(obj)
            end
        end

        % Button pushed function: Button_Select_Path
        function Button_Select_PathPushed(app, event)
            selected_dir = uigetdir();
            if selected_dir == 0
                warndlg('Please select an avaliable path!','Warning','modal');
                return
            end
            
            app.glSavePath = selected_dir;
            app.SavePathEditField.Value = selected_dir;
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            if isempty(app.glCamIndex)
                warndlg('Please select a camera!','Warning','modal');
                return
            end
            
            app.CaptureButton.BackgroundColor = 'red';
            savepath = app.glSavePath;  %the folder
            
            if isempty(savepath)
                filter = {'*.bmp';'*.jpg';'*.png';'*.tif';'*.gif'};
                [filename,savepath,indx] = uiputfile(filter, 'Save As', 'image_01');
                if isequal(savepath,0)
                    warndlg('Please select a save path and format!','Warning','modal');
                    return
                else
                    app.glSaveFormat = char(filter(indx));
                    fm = abs(app.glSaveFormat);
                    fm(fm==abs('*'))=[]; % Delect '*'
                    app.glSaveFormat = char(fm);
                    %                     disp(app.glSaveFormat)
                end
            else
                if isempty(app.glSaveFormat)
                    list = {'.bmp','.jpg','.png','.tif','.gif'};
                    [indx,tf] = listdlg('ListString',list, ...
                        'PromptString','Select a saving format', ...
                        'SelectionMode', 'Single', ...
                        'ListSize', [150,100]);
                    if (tf == 0)
                        return
                    end
                    app.glSaveFormat = char(list(indx));
                end
                disp(app.glSaveFormat)
                filename = ['image_01' app.glSaveFormat];
            end
            
            if(app.glCaptureFrames > 1)
                nametemplate = 'image_%02d';  %name pattern
                nametemplate = [nametemplate app.glSaveFormat];
                imnum = 0;        %starting image number
                for K = 1 : app.glCaptureFrames
                    frame = snapshot(app.glCamObj); %capture the image
                    imnum = imnum + 1;
                    thisfile = sprintf(nametemplate, imnum);  %create filename
                    fullname = fullfile(savepath, thisfile);
                    imwrite( frame, fullname);
                end
            else
                frame = snapshot(app.glCamObj); %capture one frame
                fullname = fullfile(savepath, filename);
                disp(fullname)
                imwrite( frame, fullname);
            end
            
            app.CaptureButton.BackgroundColor = 'white';
            imshow(frame, 'parent', app.UIAxes);
            
        end

        % Button pushed function: ButtonStartRecord
        function ButtonStartRecordPushed(app, event)
            if isempty(app.glCamIndex)
                warndlg('Please select a camera!','Warning','modal');
                return
            end
            
            savepath = app.glSavePath;  % Video save path
            if isempty(savepath)
                [filename,savepath,indx] = uiputfile('Video.avi');
            else
                filename = 'Video.avi';
            end
            app.glStopRecord = true;
            TIME = 0;
            DISPLAY = app.EditFieldRecordTime;
            
            fullname = fullfile(savepath, filename);
            vidWriter = VideoWriter(fullname);
            open(vidWriter);
            tic;
            start(app.glTimer);
            while (app.glStopRecord)
                % Acquire frame for processing
                img = snapshot(app.glCamObj);
                % Write frame to video
                writeVideo(vidWriter,img);
                t = toc;
                str = format_time(TIME + t);
                set(DISPLAY, 'Value', str);
            end
            
            %----- SUBFUNCTION: format_time(...) ---------------------------------------------------------------
            function str = format_time(t)
                hrs = floor(t/3600);
                min = floor(t/60 - 60*hrs);
                sec = t - 60*(min + 60*hrs);
                if hrs < 10
                    h = sprintf('0%1.0f:', hrs);
                else
                    h = sprintf('%1.0f:', hrs);
                end
                if min < 10
                    m = sprintf('0%1.0f:', min);
                else
                    m = sprintf('%1.0f:', min);
                end
                if sec < 9.9995
                    s = sprintf('0%1.3f', sec);
                else
                    s = sprintf('%1.3f', sec);
                end
                str = [h m s];
            end
        end

        % Value changed function: CaptureFramesEditField
        function CaptureFramesEditFieldValueChanged(app, event)
            app.glCaptureFrames = app.CaptureFramesEditField.Value;
            
        end

        % Value changed function: SavePathEditField
        function SavePathEditFieldValueChanged(app, event)
            app.glSavePath = app.SavePathEditField.Value;
        end

        % Value changed function: CheckBox_slash_LT_RD
        function CheckBox_slash_LT_RDValueChanged(app, event)
            value = app.CheckBox_slash_LT_RD.Value;
            
        end

        % Button pushed function: ButtonStopRecord
        function ButtonStopRecordPushed(app, event)
            app.glStopRecord = false;
            stop(app.glTimer);
        end

        % Button pushed function: Button_Select_image
        function Button_Select_imagePushed(app, event)
            [file,path] = uigetfile({'*.*'},'File Selector');
            app.glSelectImage = [path file];
            app.ImageEditField.Value = app.glSelectImage;
        end

        % Button pushed function: GrayscaleHistogramButton
        function GrayscaleHistogramButtonPushed(app, event)
            if isempty(app.glSelectImage)
                warndlg('Please select an image!','Warning','modal');
                return
            end
            I = imread(app.glSelectImage);
            imhist(I);
        end

        % Button pushed function: Button_RGB_Histogram
        function Button_RGB_HistogramPushed(app, event)
            if isempty(app.glSelectImage)
                warndlg('Please select an image!','Warning','modal');
                return
            end
            I = imread(app.glSelectImage);
            
%             figure;
            subplot(1,3,1);
            his_r = imhist(I(:,:,1));
            x_r=0:1:255;
            plot(x_r, his_r, 'r');
            fill([x_r,fliplr(x_r)],[zeros(size(his_r')),fliplr(his_r')],'r');%画填充颜色
            title('I). 红色分量直方图');
            
            subplot(1,3,2);
            his_g = imhist(I(:,:,2));
            x_g=0:1:255;
            plot(x_g, his_g, 'g');
            fill([x_g,fliplr(x_g)],[zeros(size(his_g')),fliplr(his_g')],'g');%画填充颜色
            title('I). 绿色分量直方图');
            
            subplot(1,3,3);
            his_b = imhist(I(:,:,3));
            x_b=0:1:255;
            plot(x_b, his_b, 'b');  
            fill([x_g,fliplr(x_g)],[zeros(size(his_b')),fliplr(his_b')],'b');%画填充颜色
            title('I). 蓝色分量直方图');
            
        end

        % Value changed function: mergeCheckBox
        function mergeCheckBoxValueChanged(app, event)
            value = app.mergeCheckBox.Value;
            
        end

        % Button pushed function: RGBMeanButton
        function RGBMeanButtonPushed(app, event)
            if isempty(app.glSelectImage)
                warndlg('Please select an image!','Warning','modal');
                return
            end
            I = imread(app.glSelectImage);
%             R = I(:,:,1);
%             G = I(:,:,2);
%             B = I(:,:,3);
%             
%             R = reshape(R, [s(1),s(2)]);
%             G = reshape(G, [s(1),s(2)]);
%             B = reshape(B, [s(1),s(2)]);
%             r_mean = mean(mean(R)); % 求R分量的平均值
%             g_mean = mean(mean(G)); % 求G分量的平均值
%             b_mean = mean(mean(B)); % 求B分量的平均值
%             r_mean = mean(R); % 求R分量的平均值
%             g_mean = mean(G); % 求G分量的平均值
%             b_mean = mean(B); % 求B分量的平均值
            mm = mean(mean(I));
            r_mean = mm(1); % 求R分量的平均值
            g_mean = mm(2); % 求G分量的平均值
            b_mean = mm(3); % 求B分量的平均值
            disp(r_mean);
            disp(g_mean);
            disp(b_mean);
            
            histogram(mm);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AiCamUIFigure = uifigure;
            app.AiCamUIFigure.Name = 'AiCam';
            app.FileMenu = uimenu(app.AiCamUIFigure);
            app.FileMenu.Text = 'File';
            app.DevicesMenu = uimenu(app.AiCamUIFigure);
            app.DevicesMenu.Text = 'Devices';
            app.OptionsMenu = uimenu(app.AiCamUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.CaptureMenu = uimenu(app.AiCamUIFigure);
            app.CaptureMenu.Text = 'Capture';
            app.TabGroup = uitabgroup(app.AiCamUIFigure);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.CameraListListBoxLabel = uilabel(app.PreviewTab);
            app.CameraListListBoxLabel.Text = 'Camera List';
            app.CameraListListBox = uilistbox(app.PreviewTab);
            app.CameraListListBox.Items = {};
            app.CameraListListBox.ValueChangedFcn = createCallbackFcn(app, @CameraListListBoxValueChanged, true);
            app.CameraListListBox.Value = {};
            app.ResolutionsButtonGroup = uibuttongroup(app.PreviewTab);
            app.ResolutionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResolutionsButtonGroupSelectionChanged, true);
            app.ResolutionsButtonGroup.Title = 'Resolutions';
            app.PreviewButton = uibutton(app.PreviewTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CheckBox_slash_LT_RD = uicheckbox(app.PreviewTab);
            app.CheckBox_slash_LT_RD.ValueChangedFcn = createCallbackFcn(app, @CheckBox_slash_LT_RDValueChanged, true);
            app.CheckBox_slash_LT_RD.Text = 'LT_RD';
            app.CaptureRecordTab = uitab(app.TabGroup);
            app.CaptureRecordTab.Title = 'Capture&Record';
            app.SavePathEditFieldLabel = uilabel(app.CaptureRecordTab);
            app.SavePathEditFieldLabel.Text = 'Save Path:';
            app.SavePathEditField = uieditfield(app.CaptureRecordTab, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @SavePathEditFieldValueChanged, true);
            app.Button_Select_Path = uibutton(app.CaptureRecordTab, 'push');
            app.Button_Select_Path.ButtonPushedFcn = createCallbackFcn(app, @Button_Select_PathPushed, true);
            app.Button_Select_Path.Text = '...';
            app.CapturePanel = uipanel(app.CaptureRecordTab);
            app.CapturePanel.Title = 'Capture';
            app.CaptureFramesLabel = uilabel(app.CapturePanel);
            app.CaptureFramesLabel.Text = 'Capture Frames:';
            app.CaptureFramesEditField = uieditfield(app.CapturePanel, 'numeric');
            app.CaptureFramesEditField.ValueChangedFcn = createCallbackFcn(app, @CaptureFramesEditFieldValueChanged, true);
            app.CaptureFramesEditField.Value = 1;
            app.CaptureButton = uibutton(app.CapturePanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.RecordPanel = uipanel(app.CaptureRecordTab);
            app.RecordPanel.Title = 'Record';
            app.ButtonStartRecord = uibutton(app.RecordPanel, 'push');
            app.ButtonStartRecord.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartRecordPushed, true);
            app.ButtonStartRecord.Icon = 'RecordStart.jpg';
            app.ButtonStartRecord.Text = '';
            app.ButtonStopRecord = uibutton(app.RecordPanel, 'push');
            app.ButtonStopRecord.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopRecordPushed, true);
            app.ButtonStopRecord.Icon = 'RecordStop.jpg';
            app.ButtonStopRecord.Text = '';
            app.EditFieldRecordTime = uieditfield(app.RecordPanel, 'text');
            app.EditFieldRecordTime.Value = '00:00:00 00';
            app.RecordStateLamp = uilamp(app.RecordPanel);
            app.UIAxes = uiaxes(app.CaptureRecordTab);
            title(app.UIAxes, 'Capture Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ImageProcessTab = uitab(app.TabGroup);
            app.ImageProcessTab.Title = 'Image Process';
            app.Panel_Still_Image = uipanel(app.ImageProcessTab);
            app.Panel_Still_Image.Title = 'Still Image Process';
            app.ImageEditFieldLabel = uilabel(app.Panel_Still_Image);
            app.ImageEditFieldLabel.Text = 'Image';
            app.ImageEditField = uieditfield(app.Panel_Still_Image, 'text');
            app.Button_Select_image = uibutton(app.Panel_Still_Image, 'push');
            app.Button_Select_image.ButtonPushedFcn = createCallbackFcn(app, @Button_Select_imagePushed, true);
            app.Button_Select_image.Text = '...';
            app.Button_RGB_Histogram = uibutton(app.Panel_Still_Image, 'push');
            app.Button_RGB_Histogram.ButtonPushedFcn = createCallbackFcn(app, @Button_RGB_HistogramPushed, true);
            app.Button_RGB_Histogram.Text = 'RGB Histogram';
            app.GrayscaleHistogramButton = uibutton(app.Panel_Still_Image, 'push');
            app.GrayscaleHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleHistogramButtonPushed, true);
            app.GrayscaleHistogramButton.Text = 'Grayscale  Histogram';
            app.mergeCheckBox = uicheckbox(app.Panel_Still_Image);
            app.mergeCheckBox.ValueChangedFcn = createCallbackFcn(app, @mergeCheckBoxValueChanged, true);
            app.mergeCheckBox.Text = 'merge';
            app.RGBMeanButton = uibutton(app.Panel_Still_Image, 'push');
            app.RGBMeanButton.ButtonPushedFcn = createCallbackFcn(app, @RGBMeanButtonPushed, true);
            app.RGBMeanButton.Text = 'RGB Mean';
            app.Panel_Real_Image = uipanel(app.ImageProcessTab);
            app.Panel_Real_Image.Title = 'Real Image Process';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AiCam

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AiCamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AiCamUIFigure)
        end
    end
end